classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResultsLabel                   matlab.ui.control.Label
        Labels                         matlab.ui.control.Label
        DataLoggerLabel                matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        HTML_3                         matlab.ui.control.HTML
        ModelComparisonCheckBox        matlab.ui.control.CheckBox
        HTML_2                         matlab.ui.control.HTML
        HTML                           matlab.ui.control.HTML
        ProgressTextArea               matlab.ui.control.TextArea
        ProgressTextAreaLabel          matlab.ui.control.Label
        VisualizationCheckBox_3        matlab.ui.control.CheckBox
        VisualizationCheckBox_2        matlab.ui.control.CheckBox
        VisualizationCheckBox          matlab.ui.control.CheckBox
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        NormalizationCheckBox          matlab.ui.control.CheckBox
        StandardizationCheckBox        matlab.ui.control.CheckBox
        FineTuningOnCheckBox           matlab.ui.control.CheckBox
        StatusLamp_3                   matlab.ui.control.Lamp
        StatusLamp_3Label              matlab.ui.control.Label
        Tree_3                         matlab.ui.container.CheckBoxTree
        DynamicNode                    matlab.ui.container.TreeNode
        R1secNode                      matlab.ui.container.TreeNode
        R3secNode                      matlab.ui.container.TreeNode
        R5secNode                      matlab.ui.container.TreeNode
        R10secNode                     matlab.ui.container.TreeNode
        R30secNode                     matlab.ui.container.TreeNode
        EquilibriumNode                matlab.ui.container.TreeNode
        EnergyEfficientyNode           matlab.ui.container.TreeNode
        LabelNode                      matlab.ui.container.TreeNode
        AvailableCapacityAhNode        matlab.ui.container.TreeNode
        EmergyEfficiencyNode           matlab.ui.container.TreeNode
        AgingModeNode                  matlab.ui.container.TreeNode
        LLINode                        matlab.ui.container.TreeNode
        LAMpNode                       matlab.ui.container.TreeNode
        Tree_2                         matlab.ui.container.CheckBoxTree
        RandomForestNode               matlab.ui.container.TreeNode
        SupportVectorMachineNode       matlab.ui.container.TreeNode
        LSTMNode                       matlab.ui.container.TreeNode
        GaussianProcessRegressionNode  matlab.ui.container.TreeNode
        TransferLearningNode_2         matlab.ui.container.TreeNode
        Tree                           matlab.ui.container.CheckBoxTree
        RPTNode                        matlab.ui.container.TreeNode
        DriveCycleNode                 matlab.ui.container.TreeNode
        StatusLamp_2                   matlab.ui.control.Lamp
        StatusLamp_2Label              matlab.ui.control.Label
        DeploymentButton               matlab.ui.control.StateButton
        ModelButton                    matlab.ui.control.StateButton
        FeaturesLabelsButton           matlab.ui.control.StateButton
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        DataButton                     matlab.ui.control.StateButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        Property % Description
        AppPath_RPT = ''
        AppPath_DC = ''
        LoadRPT = false
        LoadDC = false
        NumRPTFiles = 0
        NumDCFiles = 0
        ProcessData_VQ = struct()
        FeatureTable = table()
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataButton
        function DataButtonValueChanged(app, event)
            addpath('D:\JCW\Projects\KEPCO_ESS_Local\ExperimentalData\FeatureEngineering\Lab_RPT_Analysis\App');

            % 0. 로그 초기화
            app.ProgressTextArea.Value = {'=== Data Load Started ==='};
            drawnow limitrate;
            
            % 1. Tree 체크박스에서 체크된 항목 읽기
            checkedNodes = app.Tree.CheckedNodes;
            if isempty(checkedNodes)
                uialert(app.UIFigure, 'Please check the data items to load (RPT / Drive Cycle).', 'No Selection');
                app.DataLoadButton.Value = 0;
                return;
            end
            
            app.LoadRPT = false;
            app.LoadDC = false;
            for i = 1:numel(checkedNodes)
                nodeName = checkedNodes(i).Text;
                if strcmp(nodeName, 'RPT')
                    app.LoadRPT = true;
                elseif strcmp(nodeName, 'Drive Cycle')
                    app.LoadDC = true;
                end
            end
            app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  Selected: RPT=%s, DC=%s', mat2str(app.LoadRPT), mat2str(app.LoadDC))}]; drawnow limitrate;
            
            % 2. 폴더 경로 선택
            if app.LoadRPT
                app.AppPath_RPT = uigetdir('D:\JCW\Projects\KEPCO_ESS_Local\Experimental Data', 'Select RPT Data Folder');
                if app.AppPath_RPT == 0
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [Canceled] RPT folder selection canceled'}]; drawnow limitrate;
                    app.LoadRPT = false;
                else
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  RPT Path: %s', app.AppPath_RPT)}]; drawnow limitrate;
                end
            end
            
            if app.LoadDC
                app.AppPath_DC = uigetdir('D:\JCW\Projects\KEPCO_ESS_Local\Experimental Data', 'Select Drive Cycle Data Folder');
                if app.AppPath_DC == 0
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [Canceled] DC folder selection canceled'}]; drawnow limitrate;
                    app.LoadDC = false;
                    app.AppPath_DC = '';
                else
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  DC Path: %s', app.AppPath_DC)}]; drawnow limitrate;
                end
            else
                app.AppPath_DC = '';
            end
            
            if ~app.LoadRPT && ~app.LoadDC
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [Warning] No valid folders selected.'}]; drawnow limitrate;
                app.StatusLamp.Color = [1.00, 0.00, 0.00];
                return;
            end
            
            % 3. 상태등 노란색(스캔 중)
            app.StatusLamp.Color = [1.00, 0.84, 0.00];
            drawnow;
            
            % 4. 파일 스캔 (존재 여부 + 개수만 확인)
            app.NumRPTFiles = 0;
            app.NumDCFiles = 0;
            
            if app.LoadRPT && ~isempty(app.AppPath_RPT)
                rpt_files = dir(fullfile(app.AppPath_RPT, '**\*_RPT_*.csv'));
                app.NumRPTFiles = length(rpt_files);
                if app.NumRPTFiles > 0
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [OK] Found %d RPT files', app.NumRPTFiles)}]; drawnow limitrate;
                else
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [X] No RPT files found (*_RPT_*.csv)'}]; drawnow limitrate;
                end
            end
            
            if app.LoadDC && ~isempty(app.AppPath_DC)
                dc_mat = dir(fullfile(app.AppPath_DC, '**\*.mat'));
                dc_csv = dir(fullfile(app.AppPath_DC, '**\*Drive*.csv'));
                app.NumDCFiles = length(dc_mat) + length(dc_csv);
                if app.NumDCFiles > 0
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [OK] Found %d DC files (mat:%d, csv:%d)', app.NumDCFiles, length(dc_mat), length(dc_csv))}]; drawnow limitrate;
                else
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [X] No Drive Cycle files found'}]; drawnow limitrate;
                end
            end
            
            % 5. 결과 표시
            if (app.NumRPTFiles + app.NumDCFiles) > 0
                app.StatusLamp.Color = [0.00, 1.00, 0.00]; % Green
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('=== Scan Complete: Total %d files ===', app.NumRPTFiles + app.NumDCFiles)}];
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'>> Please click [Features & Labels] button.'}]; drawnow limitrate;
            else
                app.StatusLamp.Color = [1.00, 0.00, 0.00];
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'=== Files Not Found ==='}]; drawnow limitrate;
            end

        end

        % Value changed function: FeaturesLabelsButton
        function FeaturesLabelsButtonValueChanged(app, event)
            addpath('D:\JCW\Projects\KEPCO_ESS_Local\ExperimentalData\FeatureEngineering\Lab_RPT_Analysis\App');

            % HTML progress bar template
            barTpl = ['<style>*{margin:0;padding:0;box-sizing:border-box;font-family:Arial,sans-serif;}' ...
              '.title{text-align:center;font-size:10px;color:#999;letter-spacing:2px;margin-bottom:4px;}' ...
              '.row{display:flex;align-items:center;gap:12px;padding:0 4px;}' ...
              '.track{flex:1;background:#d9d9d9;height:30px;border-radius:2px;}' ...
              '.fill{height:100%%;background:#6cc0f5;border-radius:2px;}' ...
              '.val{font-size:32px;font-weight:bold;color:#333;min-width:60px;text-align:right;}' ...
              '.ticks{display:flex;justify-content:space-between;padding:4px 4px 0;font-size:10px;color:#999;}</style>' ...
              '<div class="title">PROGRESS</div>' ...
              '<div class="row"><div class="track"><div class="fill" style="width:%d%%"></div></div>' ...
              '<div class="val">%02d%%</div></div>' ...
              '<div class="ticks" style="margin-right:72px;">' ...
              '<span>0</span><span>20</span><span>40</span><span>60</span><span>80</span><span>100</span></div>'];

            if (app.NumRPTFiles + app.NumDCFiles) == 0
                uialert(app.UIFigure, 'No scanned files. Run [Data Load] first.', 'Error');
                app.FeaturesLabelsButton.Value = 0;
                return;
            end
            
            app.StatusLamp_3.Color = [1.00, 0.84, 0.00];
            app.HTML.HTMLSource = sprintf(barTpl, 0, 0); drawnow;
            app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {''}; {'=== Features & Labels Start ==='}]; drawnow;
            
            try
                % ====================================================
                % Step 1: Process RPT files one by one (0% ~ 50%)
                % ====================================================
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [1/2] Processing RPT files...'}]; drawnow;
                
                App_VQ_grid = struct();
                
                if app.LoadRPT && ~isempty(app.AppPath_RPT)
                    csv_files = dir(fullfile(app.AppPath_RPT, '**\*_RPT_*.csv'));
                    nFiles = length(csv_files);
                    
                    for k = 1:nFiles
                        fn = csv_files(k).name;
                        fp = fullfile(csv_files(k).folder, fn);
                        
                        tok = regexp(fn, 'Ch(\d+)_RPT_(\d+)cyc', 'tokens');
                        if isempty(tok), continue; end
                        
                        ch_key = sprintf('Ch%02d', str2double(tok{1}{1}));
                        cyc_key = sprintf('cyc%d', str2double(tok{1}{2}));
                        
                        ch_data = process_single_rpt_file(fp, 3, [8, 10], 20);
                        
                        if ~isfield(App_VQ_grid, cyc_key)
                            App_VQ_grid.(cyc_key) = struct();
                        end
                        App_VQ_grid.(cyc_key).(ch_key) = ch_data;
                        
                        % Update progress bar after each file (0%~50%)
                        pct = round((k / nFiles) * 50);
                        app.HTML.HTMLSource = sprintf(barTpl, pct, pct);
                        drawnow;
                    end
                end
                
                app.ProcessData_VQ = App_VQ_grid;
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [1/2] RPT processing done!'}]; drawnow;
                
                % ====================================================
                % Step 2: Feature Extraction (50% ~ 100%)
                % ====================================================
                app.HTML.HTMLSource = sprintf(barTpl, 50, 50); drawnow;
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [2/2] Extracting features (dQ/dV, Peak)...'}]; drawnow;
                
                master_ruler_path = 'D:\JCW\Projects\KEPCO_ESS_Local\ExperimentalData\FeatureEngineering\Lab_RPT_Analysis\Dataset\MasterRulers.mat';
                app.FeatureTable = App_FeatureExtractor(app.ProcessData_VQ, master_ruler_path);
                
                app.HTML.HTMLSource = sprintf(barTpl, 100, 100); drawnow;
                
                if isempty(app.FeatureTable)
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [Warning] No features extracted'}]; drawnow;
                    app.StatusLamp_3.Color = [1.00, 0.00, 0.00];
                    app.FeaturesLabelsButton.Value = 0;
                    return;
                end
                
                app.StatusLamp_3.Color = [0.00, 1.00, 0.00];
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [2/2] Done! (%dx%d)', size(app.FeatureTable,1), size(app.FeatureTable,2))}];
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'=== Features & Labels Complete ==='}]; drawnow;
                
                % ====================================================
                % Visualization (if checked)
                % ====================================================
                visDir = fullfile(fileparts(mfilename('fullpath')), 'Results');
                
                % Data Load Visualization
                if app.VisualizationCheckBox.Value
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [VIS] Running Data Load visualization...'}]; drawnow;
                    try
                        App_Visualizer_DataLoad(app.ProcessData_VQ, visDir);
                    catch ME_vis
                        app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [VIS ERROR] %s', ME_vis.message)}]; drawnow;
                    end
                end
                
                % Features & Labels Visualization
                if app.VisualizationCheckBox_2.Value
                    app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {'  [VIS] Running Features & Labels visualization...'}]; drawnow;
                    try
                        % Collect checked Tree_3 node names
                        checkedNodes_T3 = {};
                        if ~isempty(app.Tree_3.CheckedNodes)
                            for nn = 1:numel(app.Tree_3.CheckedNodes)
                                checkedNodes_T3{end+1} = app.Tree_3.CheckedNodes(nn).Text;
                            end
                        end
                        App_Visualizer_Features(app.ProcessData_VQ, app.FeatureTable, checkedNodes_T3, visDir);
                    catch ME_vis
                        app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [VIS ERROR] %s', ME_vis.message)}]; drawnow;
                    end
                end
                
            catch ME
                app.StatusLamp_3.Color = [1.00, 0.00, 0.00];
                app.HTML.HTMLSource = sprintf(barTpl, 0, 0); drawnow;
                app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {sprintf('  [ERROR] %s', ME.message)}]; drawnow;
                app.FeaturesLabelsButton.Value = 0;
            end

        end

        % Value changed function: ProgressTextArea
        function ProgressTextAreaValueChanged(app, event)
            timestamp = datestr(now, 'HH:MM:SS');
            newLine = sprintf('[%] %s', timestamp, event);
            app.ProgressTextArea.Value = [app.ProgressTextArea.Value; {newLine}];
            scroll(app.ProgressTextArea, 'bottom');
            drawnow limitrate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataButton = uibutton(app.UIFigure, 'state');
            app.DataButton.ValueChangedFcn = createCallbackFcn(app, @DataButtonValueChanged, true);
            app.DataButton.Text = 'Data';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.FeaturesLabelsButton = uibutton(app.UIFigure, 'state');
            app.FeaturesLabelsButton.ValueChangedFcn = createCallbackFcn(app, @FeaturesLabelsButtonValueChanged, true);
            app.FeaturesLabelsButton.Text = {'Features'; '&'; 'Labels'};
            app.ModelButton = uibutton(app.UIFigure, 'state');
            app.ModelButton.Text = 'Model';
            app.DeploymentButton = uibutton(app.UIFigure, 'state');
            app.DeploymentButton.Text = 'Deployment';
            app.StatusLamp_2Label = uilabel(app.UIFigure);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_2 = uilamp(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.RPTNode = uitreenode(app.Tree);
            app.RPTNode.Text = 'RPT';
            app.DriveCycleNode = uitreenode(app.Tree);
            app.DriveCycleNode.Text = 'Drive Cycle';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.RPTNode];
            app.Tree_2 = uitree(app.UIFigure, 'checkbox');
            app.RandomForestNode = uitreenode(app.Tree_2);
            app.RandomForestNode.Text = 'Random Forest';
            app.SupportVectorMachineNode = uitreenode(app.Tree_2);
            app.SupportVectorMachineNode.Text = 'Support Vector Machine';
            app.LSTMNode = uitreenode(app.Tree_2);
            app.LSTMNode.Text = 'LSTM';
            app.GaussianProcessRegressionNode = uitreenode(app.Tree_2);
            app.GaussianProcessRegressionNode.Text = 'Gaussian Process Regression';
            app.TransferLearningNode_2 = uitreenode(app.Tree_2);
            app.TransferLearningNode_2.Text = 'Transfer Learning';
            app.Tree_3 = uitree(app.UIFigure, 'checkbox');
            app.DynamicNode = uitreenode(app.Tree_3);
            app.DynamicNode.Text = 'Dynamic';
            app.R1secNode = uitreenode(app.DynamicNode);
            app.R1secNode.Text = 'R1sec';
            app.R3secNode = uitreenode(app.DynamicNode);
            app.R3secNode.Text = 'R3sec';
            app.R5secNode = uitreenode(app.DynamicNode);
            app.R5secNode.Text = 'R5sec';
            app.R10secNode = uitreenode(app.DynamicNode);
            app.R10secNode.Text = 'R10sec';
            app.R30secNode = uitreenode(app.DynamicNode);
            app.R30secNode.Text = 'R30sec';
            app.EquilibriumNode = uitreenode(app.Tree_3);
            app.EquilibriumNode.Text = 'Equilibrium';
            app.EnergyEfficientyNode = uitreenode(app.EquilibriumNode);
            app.EnergyEfficientyNode.Text = 'Energy Efficienty';
            app.LabelNode = uitreenode(app.Tree_3);
            app.LabelNode.Text = 'Label';
            app.AvailableCapacityAhNode = uitreenode(app.LabelNode);
            app.AvailableCapacityAhNode.Text = 'Available Capacity (Ah)';
            app.EmergyEfficiencyNode = uitreenode(app.LabelNode);
            app.EmergyEfficiencyNode.Text = 'Emergy Efficiency (%)';
            app.AgingModeNode = uitreenode(app.LabelNode);
            app.AgingModeNode.Text = 'Aging Mode (%)';
            app.LLINode = uitreenode(app.AgingModeNode);
            app.LLINode.Text = 'LLI';
            app.LAMpNode = uitreenode(app.AgingModeNode);
            app.LAMpNode.Text = 'LAMp';
            % Assign Checked Nodes
            app.Tree_3.CheckedNodes = [app.R1secNode, app.R3secNode, app.R5secNode, app.R10secNode, app.R30secNode, app.EnergyEfficientyNode, app.AvailableCapacityAhNode, app.EmergyEfficiencyNode, app.LLINode, app.LAMpNode, app.DynamicNode, app.EquilibriumNode, app.LabelNode, app.AgingModeNode];
            app.StatusLamp_3Label = uilabel(app.UIFigure);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_3 = uilamp(app.UIFigure);
            app.FineTuningOnCheckBox = uicheckbox(app.UIFigure);
            app.FineTuningOnCheckBox.Text = 'Fine Tuning On';
            app.FineTuningOnCheckBox.Value = true;
            app.StandardizationCheckBox = uicheckbox(app.UIFigure);
            app.StandardizationCheckBox.Text = 'Standardization';
            app.NormalizationCheckBox = uicheckbox(app.UIFigure);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.DescriptionTextArea.Value = {'This application is for state estimator for only using Lab Dataset (RPT and Drive Cycle). You can choose the dataset option using dropdown menu, features and labels, and also ML / DL options.As the model development has finished, you can delploy your model. '};
            app.VisualizationCheckBox = uicheckbox(app.UIFigure);
            app.VisualizationCheckBox.Text = 'Visualization';
            app.VisualizationCheckBox_2 = uicheckbox(app.UIFigure);
            app.VisualizationCheckBox_2.Text = 'Visualization';
            app.VisualizationCheckBox_2.Value = true;
            app.VisualizationCheckBox_3 = uicheckbox(app.UIFigure);
            app.VisualizationCheckBox_3.Text = 'Visualization';
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.ProgressTextArea.ValueChangedFcn = createCallbackFcn(app, @ProgressTextAreaValueChanged, true);
            app.HTML = uihtml(app.UIFigure);
            app.HTML_2 = uihtml(app.UIFigure);
            app.ModelComparisonCheckBox = uicheckbox(app.UIFigure);
            app.ModelComparisonCheckBox.Text = 'Model Comparison';
            app.HTML_3 = uihtml(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.DataLoggerLabel = uilabel(app.UIFigure);
            app.DataLoggerLabel.Text = 'Data Logger';
            app.Labels = uilabel(app.UIFigure);
            app.Labels.Text = 'Labels';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end